classdef waves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AmplitudeEditField       matlab.ui.control.EditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        ExitButton               matlab.ui.control.Button
        TypesDropDown            matlab.ui.control.DropDown
        TypesDropDownLabel       matlab.ui.control.Label
        GridButtonGroup          matlab.ui.container.ButtonGroup
        OffButton                matlab.ui.control.RadioButton
        OnButton                 matlab.ui.control.RadioButton
        PlotButton               matlab.ui.control.Button
        FrequencyEditField       matlab.ui.control.EditField
        FrequencyLabel           matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        type = 'Sine Wave'
        frq = 1
        amp = 1
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Selection changed function: GridButtonGroup
        function GridButtonGroupSelectionChanged(app, event)
            selectedButton = app.GridButtonGroup.SelectedObject;
            if selectedButton == app.OnButton
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
            elseif selectedButton == app.OffButton
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
            end
        end

        % Value changed function: TypesDropDown
        function TypesDropDownValueChanged(app, event)
            value = app.TypesDropDown.Value;
            app.type = value;
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.frq = str2double(value);
            if isnan(app.frq)
                app.frq = 1;
            end
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.amp = str2double(value);
            if isnan(app.amp)
                app.amp = 1;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            omega = 2*pi*app.frq;
            t = 0:0.001:1;
            switch app.type
                case 'Sine Wave'
                    f = app.amp*sin(omega*t);
                case 'Cosine Wave'
                    f = app.amp*cos(omega*t);
                case 'Triangle Wave'
                    f = app.amp*sawtooth(omega*t + pi/2, 0.5);
                case 'Square Wave'
                    f = app.amp*square(omega*t);
            end
            plot(app.UIAxes, t, f)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            zlabel(app.UIAxes, 'Z')
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = '1';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GridButtonGroup = uibuttongroup(app.UIFigure);
            app.GridButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GridButtonGroupSelectionChanged, true);
            app.GridButtonGroup.Title = 'Grid';
            app.OnButton = uiradiobutton(app.GridButtonGroup);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.OffButton = uiradiobutton(app.GridButtonGroup);
            app.OffButton.Text = 'Off';
            app.TypesDropDownLabel = uilabel(app.UIFigure);
            app.TypesDropDownLabel.Text = 'Types';
            app.TypesDropDown = uidropdown(app.UIFigure);
            app.TypesDropDown.Items = {'Sine Wave', 'Cosine Wave', 'Triangle Wave', 'Square Wave'};
            app.TypesDropDown.ValueChangedFcn = createCallbackFcn(app, @TypesDropDownValueChanged, true);
            app.TypesDropDown.Value = 'Sine Wave';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude:';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'text');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waves

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end